classdef predict_trajectory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        RunallButton                   matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        AmatrixLabel                   matlab.ui.control.Label
        SelecttaskDropDownLabel        matlab.ui.control.Label
        SelecttaskDropDown             matlab.ui.control.DropDown
        StateNoiseSliderLabel          matlab.ui.control.Label
        StateNoiseSlider               matlab.ui.control.Slider
        ManipulatedataLabel            matlab.ui.control.Label
        ManipulatefilterLabel          matlab.ui.control.Label
        RunonestepButton               matlab.ui.control.Button
        NotaskselectedLabel            matlab.ui.control.Label
        ObservationNoiseSliderLabel    matlab.ui.control.Label
        ObservationNoiseSlider         matlab.ui.control.Slider
        SelectDirectionDropDownLabel   matlab.ui.control.Label
        SelectDirectionDropDown        matlab.ui.control.DropDown
        UIAxes_3                       matlab.ui.control.UIAxes
        NumberofneuronsEditFieldLabel  matlab.ui.control.Label
        NumberofneuronsEditField       matlab.ui.control.NumericEditField
        ResetButton                    matlab.ui.control.Button
        SortbyPDCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        datafile; % Description
        task = 0; % Description
        current_timestep = 1;  % Description
    end
    
    methods (Access = private)
        
        function ret = getAmat(app)
            ret = [app.EditField.Value, app.EditField_2.Value; ...
                app.EditField_3.Value, app.EditField_4.Value];
        end
       
        
        %TODO:
        
        function [arm_vel, mu_vel, dt, pos_est, arm_pos] = filter_neurons(app)
            %TODO: set aside training/test data
            A = getAmat(app);
            Q_mag = app.StateNoiseSlider.Value;
            Q = [1 0;0 1] .* Q_mag;
            if app.task>0
                spikes = app.datafile{1};
                dt = app.datafile{2};
                cosine_fit = app.datafile{3};
                arm_vel = app.datafile{4};
                % B regression coefficients
                B = cosine_fit.Bs;
                sigma = cosine_fit.sigma; %this represents the variance in the firing
                % R NxN matrix of noise distribution from regression
                R_mag = app.ObservationNoiseSlider.Value;
                R = sigma.*R_mag;
                sm = 2; %smoothing value
                %Mean of velocity initial time step, dimension x 1, initialize at zero
                mu = zeros(2,size(spikes,2)); %this was size(spikes,2) before but that wasn't right
                %mu(:) = (zeros(2, 1));
                %Covariance matrix of velocity initial time step
                phi = zeros(2, 2);
                
                %for i = 1:length(arm_vel)
                arm_pos(1,:) = [0,0];
                for j = 1:size(arm_vel, 1)
                    arm_pos(j+1,1) = arm_pos(j,1)+arm_vel(j,1)*dt;
                    arm_pos(j+1,2) = arm_pos(j,2)+arm_vel(j,2)*dt;
                end
                arm_pos = arm_pos(2:end,:);
                %end
                
                %for traj = 1:size(spikes, 2)
                for j = 1:size(spikes, 2)
                    if mod(sm, 2) == 0
                        mnarr = spikes(:, max(j-sm/2, 1):min(j+sm/2, size(spikes, 2)));
                    else
                        mnarr = [mean(spikes(:, max(j-floor(sm/2)-1, 1):max(j-floor(sm/2), 1)), 2) ...
                            spikes(:, max(j-floor(sm/2), 1):min(j+floor(sm/2), size(spikes, 2))) ...
                            mean(spikes(:, min(j+floor(sm/2), size(spikes, 2)):min(j+floor(sm/2)+1, size(spikes, 2))), 2)];
                    end
                    %smooth spike rates
                    %as well but it seems like it should...
                    sm_f0(:, j) = mean(mnarr, 2)/dt-cosine_fit.b0s;
                end
                pos_est(:,1) = [0;0];
                for t = 1:size(spikes, 2)
                    % one step prediction
                    mu_est = A * mu(:, t);
                    phi_est = A * phi * A' + Q;
                    pred_vals{t} = {mu_est, phi_est}; %this is to compare the model estimates and Kalman estimates
                    % Kalman Gain
                    K = phi * B' * inv(B*phi*B'+R);
                    %need to also add in prediction based on recording
                    P = (B'*sigma^(-1)*B)^(-1)*B'*sigma^(-1);
                    rec_est{t} = {P*spikes(:,t), sigma};
                    % Update the state estimate.
                    mu_est = mu_est + K * (sm_f0(:,t) - B*mu_est);
                    % update covariance estimation.
                    phi_est = phi_est - K * B * phi_est;
                    
                    mu(:, t+1) = mu_est;
                    pos_est(:,t+1) = pos_est(:,t)+mu_est*dt;
                    phi = phi_est;
                    phi_vals{t} = phi; 
                end
                pos_est = pos_est(:, 2:end)';
                mu_vel = mu(:, 2:end)';
                
                %end
                app.plot_dist(pred_vals, mu_vel, phi_vals, arm_vel, rec_est);
            elseif app.task == 0
                disp('You have not yet set the task');
            end
        end
        
        function [dir, dt] = find_direction(app)
            if length(app.SelectDirectionDropDown.Value) == 5
                if all(app.SelectDirectionDropDown.Value == 'Up-Up')
                    pos_fin = [0,1];
                end
            end
            if length(app.SelectDirectionDropDown.Value) == 8
                if all(app.SelectDirectionDropDown.Value == 'Up-Right')
                    pos_fin = [1,1];
                end
            end
            if length(app.SelectDirectionDropDown.Value) == 11
                if all(app.SelectDirectionDropDown.Value == 'Right-Right')
                    pos_fin = [1,0];
                end
            end
            if length(app.SelectDirectionDropDown.Value) == 10
                if all(app.SelectDirectionDropDown.Value == 'Down-Right')
                    pos_fin = [1,-1];
                end
            end
            if length(app.SelectDirectionDropDown.Value) == 9
                if all(app.SelectDirectionDropDown.Value == 'Down-Down')
                    pos_fin = [0,-1];
                end
                if all(app.SelectDirectionDropDown.Value == 'Down-Left')
                    pos_fin = [-1,-1];
                end
                if all(app.SelectDirectionDropDown.Value == 'Left-Left')
                    pos_fin = [-1,0];
                end
            end
            Q_mag = app.StateNoiseSlider.Value;
            dt = 0.1;
            idx = [0:dt:2*pi];
            a = -(pos_fin(1));
            dir(:,1) = a*(cos(idx)-1);
            dir(:,1) = dir(:,1) + (rand(size(dir,1), 1)-0.5)*Q_mag;
            a = -(pos_fin(2));
            dir(:,2) = a*(cos(idx)-1);
            dir(:,2) = dir(:,2) + (rand(size(dir,1), 1)-0.5)*Q_mag;
            %need to write code that produces raster data from this
        end
        
        function [spikes, cosine_fit] = make_raster(app)
            %make random cosine tuned cells
            fr = 10/pi; %maximal firing rate per bin
            num_cells = app.datafile{5}; %total number of cells - user selected
            arm_vel = app.datafile{4}; %actual velocity - from trajectories
            vel = 20;%2*max(max(arm_vel)); %this will be the maximal velocity (we multiply by 2 because the random number is 0.5)
            x_idx = rand(num_cells, 1)-0.5; %this selects some random # from -0.5 to 0.5 
            y_idx = rand(num_cells, 1)-0.5;
            p_vel(:,1) = x_idx*vel; %creates random preferred directions by multiplying the random number by the max velocity
            p_vel(:,2) = y_idx*vel;
            cosine_fit.Bs = p_vel; %sets the B terms equal to our derived preferred directions
            cosine_fit.b0s = zeros(num_cells, 1); %set baseline firing to zero because it was messing the predictions up
            %fr*rand(num_cells, 1)-0.5; %sets baseline firing to some random amount from -0.5 to 0.5 multiplied by fr
            for i = 1:length(arm_vel) %total time of trajectory
                for j = 1:length(p_vel) %total number of cells
                    angle_B = (atan(p_vel(j,2)/p_vel(j,1))); %solves for the preferred angle using arctan
                    if p_vel(j,1) < 0
                        angle_B = angle_B + pi; %have to make corrections based on MATLAB operations
                    elseif p_vel(j,2) < 0
                        angle_B = angle_B + 2*pi;
                    end
                    p_vel_sort(j) = angle_B; %going to use this to sort by preferred direction
                    angle_vel = atan(arm_vel(i,2)/arm_vel(i,1)); %finds angle of actual reach
                    if arm_vel(i,1) < 0
                        angle_vel = angle_vel + pi;
                    elseif arm_vel(i,2) < 0
                        angle_vel = angle_vel + 2*pi;
                    end
                    R_mag = app.ObservationNoiseSlider.Value; %noise of recording: will alter rasters
                    noise = normrnd(0, R_mag); %creates noise that has variance equal to the defined observation noise
                    spikes(j,i) = round(fr*(cosine_fit.b0s(j) + (cos(angle_B - angle_vel))+noise)); %put as (j,i) so the array is the same structure as previous data
                end
            end
            if app.SortbyPDCheckBox.Value == 1 %will sort spikes if checked
                [~, p_order] = sort(p_vel_sort);
                spikes = spikes(p_order,:);
                p_vel = p_vel(p_order,:);
            end
            cosine_fit.Bs = p_vel; %sets the B terms equal to our derived preferred directions
            cosine_fit.b0s = zeros(num_cells, 1); %set baseline firing to zero because it was messing the predictions up
            var_cos = var(spikes,0,2);
            cosine_fit.sigma = diag(var_cos); %assuming cells fire independently i.e. no correlation
            
        end
        
        function plot_dist(app, pred_vals, mu_vel, phi_vals, arm_vel, rec_est)
            %TODO: should we change this to be in terms of position?
            %TODO: should we normalize these distributions? 
            %range: TODO ultimately should be at which points?  on trajectory?
            x = -max(max(mu_vel)):max(max(mu_vel)); %the range of this should reflect the range of possible veloctities
            %predicted next position: pred_vals
            norm = normpdf(x, pred_vals{end}{1}(1), 1); %pred_vals{end}{2}(1)
            plot(app.UIAxes_3, norm, 'g', 'LineWidth', 2)
            hold(app.UIAxes_3, 'on');
            %measured next position: get this from ???? neurons?
            %hold(app.UIAxes_3, 'off');
            %TODO: this is a placeholder, need to figure out how to do it
            norm = normpdf(x, rec_est{end}{1}(1), 1); %rec_est{end}{2}(1)
            plot(app.UIAxes_3, norm, 'k', 'LineWidth', 2);
            %combined estimate: take from actual
            norm = normpdf(x, mu_vel(end, 1), 1); %phi_vals{end-1}(1)
            plot(app.UIAxes_3, norm, 'r', 'LineWidth', 1)
            %actual velocity
            line(app.UIAxes_3, [arm_vel(end, 1), arm_vel(end, 1)], get(app.UIAxes_3, 'YLim'), 'Color', 'b', 'LineWidth', 2);
            
            legend(app.UIAxes_3, {'One-step prediction', 'Measured velocity', 'Combined estimate', 'Actual velocity'});
            hold(app.UIAxes_3, 'off');
                 
        end
        
    end


    methods (Access = private)

        % Value changed function: NumberofneuronsEditField
        function NumberofneuronsEditFieldValueChanged(app, event)
            num_cells = app.NumberofneuronsEditField.Value;
            app.datafile{5} = num_cells;
            if app.task == 1
                [spikes, cosine_fit] = make_raster(app);
                app.datafile{1} = spikes;
                app.datafile{3} = cosine_fit;
                imagesc(app.UIAxes, spikes); %spikes{2}
                set(app.UIAxes, 'xtick', [], 'ytick', []);
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Neurons');
                set(app.UIAxes, 'XLim', [1 size(spikes, 2)], 'YLim', [1 size(spikes, 1)])
            end
            
        end

        % Value changed function: ObservationNoiseSlider
        function ObservationNoiseSliderValueChanged(app, event)
            R_mag = app.ObservationNoiseSlider.Value;
            app.datafile{8} = R_mag;
            if app.task == 1
                [spikes, cosine_fit] = make_raster(app);
                app.datafile{1} = spikes;
                app.datafile{3} = cosine_fit;
                imagesc(app.UIAxes, spikes); %spikes{2}
                set(app.UIAxes, 'xtick', [], 'ytick', []);
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Neurons');
                set(app.UIAxes, 'XLim', [1 size(spikes, 2)], 'YLim', [1 size(spikes, 1)])
            end
        end

        % Button pushed function: ResetButton
        function ResetAll(app, event)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            app.current_timestep = 1;
        end

        % Button pushed function: RunallButton
        function RunallButtonPushed(app, event)
            [arm_vel, mu_vel, dt, pos_est, arm_pos] = filter_neurons(app);
            %plot the actual arm positions and the predicted
            axis(app.UIAxes_2, [-1 1 -1 1])
            hold(app.UIAxes_2, 'off');
            max_val = max((max(abs(arm_pos))+0.001)); %to normalize to 1
            plot(app.UIAxes_2, arm_pos(:,1)./max_val, arm_pos(:,2)./max_val, 'bo-', 'LineWidth', 2);
            hold(app.UIAxes_2, 'on');
            max_val = max(max(abs(pos_est)));
            plot(app.UIAxes_2, pos_est(:,1)./max_val, pos_est(:,2)./max_val, ':ro', 'LineWidth', 2);
            legend(app.UIAxes_2, {'Actual Reach', 'Predicted Reach'});
            
        end

        % Button pushed function: RunonestepButton
        function RunonestepButtonPushed(app, event)
            %TODO: this is not currently functioning as of updates to Run all/up-up button option
            %for run all, all at once and here, one point at a time (each time button
            %is pushed) and increment current_timestep
            if app.current_timestep == 1
                [arm_vel, mu_vel, dt, pos_est, arm_pos] = filter_neurons(app); %shouldn't repeat this every time as that can mess things up
                app.datafile{4} = arm_vel;
                app.datafile{6} = pos_est;
                app.datafile{7} = arm_pos;
            else
                arm_vel = app.datafile{4};
                pos_est = app.datafile{6};
                arm_pos = app.datafile{7};
            end
            %plot the actual arm positions and the predicted
            axis(app.UIAxes_2, [-1 1 -1 1])
            hold(app.UIAxes_2, 'off');
            max_val = max((max(abs(arm_pos))+0.001)); %to normalize to 1 - may be a better way to go about this
            plot(app.UIAxes_2, arm_pos(1:app.current_timestep, 1)./max_val, arm_pos(1:app.current_timestep, 2)./max_val, 'bo-', 'LineWidth', 2);
            hold(app.UIAxes_2, 'on');
            max_val = max(max(abs(pos_est)));
            plot(app.UIAxes_2, pos_est(1:app.current_timestep, 1)./max_val, pos_est(1:app.current_timestep, 2)./max_val, ':ro', 'LineWidth', 2);
            legend(app.UIAxes_2, {'Actual Reach', 'Predicted Reach'});
            
            if app.current_timestep < size(arm_vel, 1)
                app.current_timestep = app.current_timestep + 1;
            end
        end

        % Value changed function: SelectDirectionDropDown
        function SelectDirectionDropDownValueChanged(app, event)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            app.current_timestep = 1;
            dir = app.SelectDirectionDropDown.Value;
            %load('ps5_data_2D.mat'); %this is currently using the data from HW
            [arm_vel, dt] = find_direction(app);
            %num_cells = 100; %want to let user control this
            num_cells = app.NumberofneuronsEditField.Value;
            app.datafile{5} = num_cells;
            app.datafile{4} = arm_vel;
            R_mag = app.ObservationNoiseSlider.Value;
            app.datafile{8} = R_mag;
            [spikes, cosine_fit] = make_raster(app);
            disp(cosine_fit);
            app.datafile = {spikes, dt, cosine_fit, arm_vel, num_cells}; %might want to change this in general
            imagesc(app.UIAxes, spikes);
        end

        % Value changed function: SelecttaskDropDown
        function SelecttaskDropDownValueChanged(app, event)
            value = app.SelecttaskDropDown.Value;
            if value == '2D reach task'
                %load('ps5_data_2D.mat'); %this is currently using the data from HW
                app.current_timestep = 1;
                [arm_vel, dt] = find_direction(app);
                %num_cells = 100; %want to let user control this
                num_cells = app.NumberofneuronsEditField.Value;
                app.datafile{5} = num_cells;
                app.datafile{4} = arm_vel;
                [spikes, cosine_fit] = make_raster(app);
                disp(cosine_fit);
                app.datafile = {spikes, dt, cosine_fit, arm_vel, num_cells};
                app.task = 1;
                imagesc(app.UIAxes, spikes); %spikes{2}
                set(app.UIAxes, 'xtick', [], 'ytick', []);
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Neurons');
                set(app.UIAxes, 'XLim', [1 size(spikes, 2)], 'YLim', [1 size(spikes, 1)])
                %TODO: plot the data and actual trajectory
            end
            %TODO: add additional tasks here
            
            if strcmp(value, ' ') == 0
                app.NotaskselectedLabel.Text = '';
            else
                app.NotaskselectedLabel.Text = 'No task selected';
            end
        end

        % Value changed function: StateNoiseSlider
        function StateNoiseSliderValueChanged(app, event)
            Q_mag = app.StateNoiseSlider.Value;
            [arm_vel] = find_direction(app);
            app.datafile{4} = arm_vel;
            
        end

        % Value changed function: SortbyPDCheckBox
        function SortbyPDCheckBoxValueChanged(app, event)
            value = app.SortbyPDCheckBox.Value;
            [spikes, cosine_fit] = make_raster(app);
            app.datafile{1} = spikes;
            app.datafile{3} = cosine_fit;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Prediction')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.RunallButton = uibutton(app.UIFigure, 'push');
            app.RunallButton.ButtonPushedFcn = createCallbackFcn(app, @RunallButtonPushed, true);
            app.RunallButton.Text = 'Run all';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Value = 1;
            app.AmatrixLabel = uilabel(app.UIFigure);
            app.AmatrixLabel.Text = 'A matrix';
            app.SelecttaskDropDownLabel = uilabel(app.UIFigure);
            app.SelecttaskDropDownLabel.Text = 'Select task:';
            app.SelecttaskDropDown = uidropdown(app.UIFigure);
            app.SelecttaskDropDown.Items = {' ', '2D reach task', 'Moving Car'};
            app.SelecttaskDropDown.ValueChangedFcn = createCallbackFcn(app, @SelecttaskDropDownValueChanged, true);
            app.SelecttaskDropDown.Value = ' ';
            app.StateNoiseSliderLabel = uilabel(app.UIFigure);
            app.StateNoiseSliderLabel.Text = 'State Noise';
            app.StateNoiseSlider = uislider(app.UIFigure);
            app.StateNoiseSlider.Limits = [0.1 10];
            app.StateNoiseSlider.MajorTicks = [];
            app.StateNoiseSlider.MajorTickLabels = {};
            app.StateNoiseSlider.ValueChangedFcn = createCallbackFcn(app, @StateNoiseSliderValueChanged, true);
            app.StateNoiseSlider.MinorTicks = [];
            app.StateNoiseSlider.Value = 0.1;
            app.ManipulatedataLabel = uilabel(app.UIFigure);
            app.ManipulatedataLabel.Text = 'Manipulate data';
            app.ManipulatefilterLabel = uilabel(app.UIFigure);
            app.ManipulatefilterLabel.Text = 'Manipulate filter';
            app.RunonestepButton = uibutton(app.UIFigure, 'push');
            app.RunonestepButton.ButtonPushedFcn = createCallbackFcn(app, @RunonestepButtonPushed, true);
            app.RunonestepButton.Text = 'Run one step';
            app.NotaskselectedLabel = uilabel(app.UIFigure);
            app.NotaskselectedLabel.Text = 'No task selected';
            app.ObservationNoiseSliderLabel = uilabel(app.UIFigure);
            app.ObservationNoiseSliderLabel.Text = 'Observation Noise';
            app.ObservationNoiseSlider = uislider(app.UIFigure);
            app.ObservationNoiseSlider.Limits = [0.1 10];
            app.ObservationNoiseSlider.MajorTicks = [];
            app.ObservationNoiseSlider.MajorTickLabels = {};
            app.ObservationNoiseSlider.ValueChangedFcn = createCallbackFcn(app, @ObservationNoiseSliderValueChanged, true);
            app.ObservationNoiseSlider.MinorTicks = [];
            app.ObservationNoiseSlider.Value = 1;
            app.SelectDirectionDropDownLabel = uilabel(app.UIFigure);
            app.SelectDirectionDropDownLabel.Text = 'Select Direction';
            app.SelectDirectionDropDown = uidropdown(app.UIFigure);
            app.SelectDirectionDropDown.Items = {'Up-Up', 'Up-Right', 'Right-Right', 'Down-Right', 'Down-Down', 'Down-Left', 'Left-Left'};
            app.SelectDirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDirectionDropDownValueChanged, true);
            app.SelectDirectionDropDown.Value = 'Up-Up';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Distribution')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.NumberofneuronsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofneuronsEditFieldLabel.Text = 'Number of neurons';
            app.NumberofneuronsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofneuronsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofneuronsEditFieldValueChanged, true);
            app.NumberofneuronsEditField.Value = 100;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAll, true);
            app.ResetButton.Text = 'Reset';
            app.SortbyPDCheckBox = uicheckbox(app.UIFigure);
            app.SortbyPDCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortbyPDCheckBoxValueChanged, true);
            app.SortbyPDCheckBox.Text = 'Sort by PD';
        end
    end

    methods (Access = public)

        % Construct app
        function app = predict_trajectory

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end